classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BasesButtonGroup             matlab.ui.container.ButtonGroup
        base1                        matlab.ui.control.RadioButton
        base2                        matlab.ui.control.RadioButton
        base4                        matlab.ui.control.RadioButton
        base3                        matlab.ui.control.RadioButton
        PesosPanel                   matlab.ui.container.Panel
        aEditFieldLabel              matlab.ui.control.Label
        pesos_a                      matlab.ui.control.EditField
        bEditFieldLabel              matlab.ui.control.Label
        pesos_b                      matlab.ui.control.EditField
        NmerodepuntosdecontrolLabel  matlab.ui.control.Label
        puntos_control               matlab.ui.control.NumericEditField
        CurvasButtonGroup            matlab.ui.container.ButtonGroup
        circunferencia               matlab.ui.control.RadioButton
        espiral                      matlab.ui.control.RadioButton
        cicloide                     matlab.ui.control.RadioButton
        hipotrocoide                 matlab.ui.control.RadioButton
        NmerodepuntosdeinterpolacinLabel  matlab.ui.control.Label
        puntos_interpolacion         matlab.ui.control.NumericEditField
        DibujarButton                matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
    end

    
    methods (Access = private)
              
        function [f, g, a, b, l, t, bx, by] = getParams(app)            
            a = str2num(app.pesos_a.Value);
            b = str2num(app.pesos_b.Value);
            l = app.puntos_interpolacion.Value;
            bx = zeros(1,l);
            by = zeros(1,l);
            t = zeros(1,l);
            
            if app.base1.Value
                f = @(t) t;
                g = @(t) 1-t;
                for i=1:l
                    t(i) = i / (l+1);
                end
                if app.circunferencia.Value
                    for i=1:l
                        bx(i)=cos(2*pi*t(i));
                        by(i)=sin(2*pi*t(i));
                    end 
                elseif app.cicloide.Value
                    for i=1:l
                        bx(i)=(2*pi*t(i))-sin(2*pi*t(i));
                        by(i)=1-cos(2*pi*t(i));
                    end
                elseif app.espiral.Value
                    for i=1:l
                        bx(i)=4*pi*t(i)*cos(4*pi*t(i));
                        by(i)=4*pi*t(i)*sin(4*pi*t(i));
                    end
                elseif app.hipotrocoide.Value
                    R=6;
                    r=1;
                    c=2;
                    for i=1:l
                        bx(i)=(R-r)*cos(4*pi*t(i))+c*cos(((R-r)/r)*4*pi*t(i));
                        by(i)=(R-r)*sin(4*pi*t(i))-c*sin(((R-r)/r)*4*pi*t(i));
                    end
                end
            elseif app.base2.Value
                f = @(t) t^2;
                g = @(t) 1-t^2;
                for i=1:l
                    t(i) = i / (l+1);
                end
                if app.circunferencia.Value
                    for i=1:l
                        bx(i)=cos(2*pi*t(i));
                        by(i)=sin(2*pi*t(i));
                    end 
                elseif app.cicloide.Value
                    for i=1:l
                        bx(i)=(2*pi*t(i))-sin(2*pi*t(i));
                        by(i)=1-cos(2*pi*t(i));
                    end
                elseif app.espiral.Value
                    for i=1:l
                        bx(i)=4*pi*t(i)*cos(4*pi*t(i));
                        by(i)=4*pi*t(i)*sin(4*pi*t(i));
                    end
                elseif app.hipotrocoide.Value
                    R=6;
                    r=1;
                    c=2;
                    for i=1:l
                        bx(i)=(R-r)*cos(4*pi*t(i))+c*cos(((R-r)/r)*4*pi*t(i));
                        by(i)=(R-r)*sin(4*pi*t(i))-c*sin(((R-r)/r)*4*pi*t(i));
                    end
                end
            elseif app.base3.Value
                f = @(t) sin((pi/2-0.001+t)/2);
                g = @(t) sin((pi/2-0.001-t)/2);
                for i=1:l
                    t(i) = -pi/2 + 2*0.001 + i*pi/(l+1);
                end
                if app.circunferencia.Value
                    for i=1:l
                        bx(i)=cos(2*t(i)+pi);
                        by(i)=sin(2*t(i)+pi);
                    end
                elseif app.cicloide.Value
                    for i=1:l
                        bx(i)=(2*t(i)+pi)-sin(2*t(i)+pi);                    
                        by(i)=1-cos(2*t(i)+pi);                    
                    end
                elseif app.espiral.Value
                    for i=1:l
                        bx(i)=(4*t(i)+2*pi)*cos(4*t(i)+2*pi);
                        by(i)=(4*t(i)+2*pi)*sin(4*t(i)+2*pi);
                    end
                elseif app.hipotrocoide.Value
                    R=6;
                    r=1;
                    c=2;
                    for i=1:l
                        bx(i)=(R-r)*cos(4*t(i)+2*pi)+c*cos(((R-r)/r)*4*t(i)+2*pi);
                        by(i)=(R-r)*sin(4*t(i)+2*pi)-c*sin(((R-r)/r)*4*t(i)+2*pi);
                    end
                end
            elseif app.base4.Value
                f = @(t) sinh((pi/2-0.001+t)/2);
                g = @(t) sinh((pi/2-0.001-t)/2);
                for i=1:l
                    t(i) = -pi/2 + 2*0.001 + i*pi/(l+1);
                end
                if app.circunferencia.Value
                    for i=1:l
                        bx(i)=cos(2*t(i)+pi);
                        by(i)=sin(2*t(i)+pi);
                    end
                elseif app.cicloide.Value
                    for i=1:l
                        bx(i)=(2*t(i)+pi)-sin(2*t(i)+pi);                    
                        by(i)=1-cos(2*t(i)+pi);                    
                    end                    
                elseif app.espiral.Value
                    for i=1:l
                        bx(i)=(4*t(i)+2*pi)*cos(4*t(i)+2*pi);
                        by(i)=(4*t(i)+2*pi)*sin(4*t(i)+2*pi);
                    end
                elseif app.hipotrocoide.Value
                    R=6;
                    r=1;
                    c=2;
                    for i=1:l
                        bx(i)=(R-r)*cos(4*t(i)+2*pi)+c*cos(((R-r)/r)*4*t(i)+2*pi);
                        by(i)=(R-r)*sin(4*t(i)+2*pi)-c*sin(((R-r)/r)*4*t(i)+2*pi);
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Button pushed function: DibujarButton
        function DibujarButtonPushed(app, event)
            [f, g, a, b, l, t, bx, by] = getParams(app);
            app.puntos_control.Value = size(a,2) + 1;
            err = dibujar(a,b,f,g,l,t,bx,by);
            app.UITable.Data = err;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BasesButtonGroup = uibuttongroup(app.UIFigure);
            app.BasesButtonGroup.Title = 'Bases';
            app.base1 = uiradiobutton(app.BasesButtonGroup);
            app.base1.Text = 'f(f) = t, g(t) = 1- t';
            app.base1.Value = true;
            app.base2 = uiradiobutton(app.BasesButtonGroup);
            app.base2.Text = 'f(f) = t^2, g(t) = 1- t^2';
            app.base4 = uiradiobutton(app.BasesButtonGroup);
            app.base4.Text = 'f(f) = sinh((pi/2-0.001+t)/2), g(t) = sinh((pi/2-0.001-t)/2)';
            app.base3 = uiradiobutton(app.BasesButtonGroup);
            app.base3.Text = 'f(f) = sin((pi/2-0.001+t)/2), g(t) = sin((pi/2-0.001-t)/2)';
            app.PesosPanel = uipanel(app.UIFigure);
            app.PesosPanel.Title = 'Pesos';
            app.aEditFieldLabel = uilabel(app.PesosPanel);
            app.aEditFieldLabel.Text = 'a';
            app.pesos_a = uieditfield(app.PesosPanel, 'text');
            app.pesos_a.Value = '2 1 2 3 4 1';
            app.bEditFieldLabel = uilabel(app.PesosPanel);
            app.bEditFieldLabel.Text = 'b';
            app.pesos_b = uieditfield(app.PesosPanel, 'text');
            app.pesos_b.Value = '3 2 1 5 1 2';
            app.NmerodepuntosdecontrolLabel = uilabel(app.UIFigure);
            app.NmerodepuntosdecontrolLabel.Text = 'Número de puntos de control';
            app.puntos_control = uieditfield(app.UIFigure, 'numeric');
            app.puntos_control.Value = 7;
            app.CurvasButtonGroup = uibuttongroup(app.UIFigure);
            app.CurvasButtonGroup.Title = 'Curvas';
            app.circunferencia = uiradiobutton(app.CurvasButtonGroup);
            app.circunferencia.Text = 'Circunferencia';
            app.circunferencia.Value = true;
            app.espiral = uiradiobutton(app.CurvasButtonGroup);
            app.espiral.Text = 'Espiral';
            app.cicloide = uiradiobutton(app.CurvasButtonGroup);
            app.cicloide.Text = 'Cicloide';
            app.hipotrocoide = uiradiobutton(app.CurvasButtonGroup);
            app.hipotrocoide.Text = 'Hipotrocoide';
            app.NmerodepuntosdeinterpolacinLabel = uilabel(app.UIFigure);
            app.NmerodepuntosdeinterpolacinLabel.Text = 'Número de puntos de interpolación';
            app.puntos_interpolacion = uieditfield(app.UIFigure, 'numeric');
            app.puntos_interpolacion.Value = 100;
            app.DibujarButton = uibutton(app.UIFigure, 'push');
            app.DibujarButton.ButtonPushedFcn = createCallbackFcn(app, @DibujarButtonPushed, true);
            app.DibujarButton.Text = 'Dibujar';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Error x'; 'Error y'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end